classdef ptycho_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        DeleteRowButton_3             matlab.ui.control.Button
        AddRowButton_3                matlab.ui.control.Button
        DeleteRowButton               matlab.ui.control.Button
        AddRowButton                  matlab.ui.control.Button
        Ptycho_GUIWrittenbyZehaoDongatTsinghuaUniversity20250331Label  matlab.ui.control.Label
        ReconstructButton             matlab.ui.control.Button
        PtychoreconstructionLabel     matlab.ui.control.Label
        SavedpSwitch                  matlab.ui.control.Switch
        SavedpSwitchLabel             matlab.ui.control.Label
        RotateCBEDSwitch              matlab.ui.control.Switch
        RotateCBEDSwitchLabel         matlab.ui.control.Label
        Np_padtoSpinner               matlab.ui.control.Spinner
        Np_padtoSpinnerLabel          matlab.ui.control.Label
        Np_bintoSpinner               matlab.ui.control.Spinner
        Np_bintoSpinnerLabel          matlab.ui.control.Label
        selectButton_2                matlab.ui.control.Button
        selectButton                  matlab.ui.control.Button
        DfolderpatternEditField       matlab.ui.control.EditField
        DfolderpatternEditFieldLabel  matlab.ui.control.Label
        FilenameEditField             matlab.ui.control.EditField
        FilenameEditFieldLabel        matlab.ui.control.Label
        UITable_2                     matlab.ui.control.Table
        PrepareButton                 matlab.ui.control.Button
        PreparedataLabel              matlab.ui.control.Label
        InitializeButton              matlab.ui.control.Button
        InitializepathandcameraLabel  matlab.ui.control.Label
        Np_presolveSpinner            matlab.ui.control.Spinner
        Np_presolveSpinnerLabel       matlab.ui.control.Label
        gpu_idSpinner                 matlab.ui.control.Spinner
        gpu_idSpinnerLabel            matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        DatapathEditField             matlab.ui.control.EditField
        DatapathEditFieldLabel        matlab.ui.control.Label
        PtychopathEditField           matlab.ui.control.EditField
        PtychopathEditFieldLabel      matlab.ui.control.Label
        CAMERADropDown                matlab.ui.control.DropDown
        CAMERADropDownLabel           matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        ENDButton                     matlab.ui.control.Button
        rbfEditField                  matlab.ui.control.NumericEditField
        rbfEditFieldLabel             matlab.ui.control.Label
        LogoutputTextArea             matlab.ui.control.TextArea
        LogoutputTextAreaLabel        matlab.ui.control.Label
        UITable_3                     matlab.ui.control.Table
        HyperparametersnotoftenusedLabel  matlab.ui.control.Label
        removeambiguitySwitch         matlab.ui.control.Switch
        removeambiguitySwitchLabel    matlab.ui.control.Label
        reg_layerEditField            matlab.ui.control.NumericEditField
        reg_layerEditFieldLabel       matlab.ui.control.Label
        NiterplotSpinner              matlab.ui.control.Spinner
        NiterplotSpinnerLabel         matlab.ui.control.Label
        blurPSFEditField              matlab.ui.control.NumericEditField
        blurPSFEditFieldLabel         matlab.ui.control.Label
        ProbemodeSpinner              matlab.ui.control.Spinner
        ProbemodeSpinnerLabel         matlab.ui.control.Label
        NiterposcorrSpinner           matlab.ui.control.Spinner
        NiterposcorrSpinnerLabel      matlab.ui.control.Label
        NiterprobeupdSpinner          matlab.ui.control.Spinner
        NiterprobeupdSpinnerLabel     matlab.ui.control.Label
        NitersaveSpinner              matlab.ui.control.Spinner
        NitersaveSpinnerLabel         matlab.ui.control.Label
        StepsizeEditField             matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel        matlab.ui.control.Label
        AccVoltEditField              matlab.ui.control.NumericEditField
        AccVoltEditFieldLabel         matlab.ui.control.Label
        alphaEditField                matlab.ui.control.NumericEditField
        alphaEditFieldLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function DeleteSelectedRowButtonPushed(app, event)
            selectedRow = app.UITable.Selection(1);  % 获取选中的行索引
            if ~isempty(selectedRow)
                app.UITable.Data(selectedRow, :) = []; % 删除选定行
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: selectButton
        function selectButtonPushed(app, event)
            app.PtychopathEditField.Value = uigetdir();
        end

        % Button pushed function: selectButton_2
        function selectButton_2Pushed(app, event)
            app.DatapathEditField.Value = uigetdir();
        end

        % Value changed function: CAMERADropDown
        function CAMERADropDownValueChanged(app, event)
            app.LogoutputLabel.Value = 'Only K3 is supported currently!';
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            app.LogoutputTextArea.Value = '';
            cd(app.PtychopathEditField.Value);
            init_dir = fullfile(app.DatapathEditField.Value, app.DfolderpatternEditField.Value);
            filename = app.FilenameEditField.Value;
            fileList = dir(fullfile(init_dir, filename));
            Table1 = zeros(size(fileList,1), 6);
            Table2 = zeros(size(fileList,1), 2);
            for index = 1:size(fileList,1)
                % read data
                data_dir = fullfile(fileparts(init_dir),'All_Data');
                if ~exist(data_dir)
                    mkdir(data_dir)
                end 
                [~, final_folder] = fileparts(fileList(index).folder);
                LogMessage(app, final_folder);
                Table1(index, :) = [index, 240, 24, 1000, 400, 1];
                Table2(index, :) = [index, -150];
            end
            app.UITable.Data = Table1;
            app.UITable_2.Data = Table2;
        end

        % Button pushed function: PrepareButton
        function PrepareButtonPushed(app, event)
            app.LogoutputTextArea.Value = '';
            OnPrepare(app);
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            currentData = app.UITable_2.Data;
            newRow = currentData(end, :); 
            newRow(1) = newRow(1) + 1;
            app.UITable_2.Data = [currentData; newRow];
        end

        % Button pushed function: AddRowButton_3
        function AddRowButton_3Pushed(app, event)
            currentData = app.UITable.Data;
            newRow = currentData(end, :); 
            newRow(1) = newRow(1) + 1;
            app.UITable.Data = [currentData; newRow];
        end

        % Button pushed function: DeleteRowButton
        function DeleteRowButtonPushed(app, event)
            selectedRow = app.UITable_2.Selection(1); 
            if ~isempty(selectedRow)
                app.UITable_2.Data(selectedRow, :) = [];
            end
        end

        % Button pushed function: DeleteRowButton_3
        function DeleteRowButton_3Pushed(app, event)
            selectedRow = app.UITable.Selection(1); 
            if ~isempty(selectedRow)
                app.UITable.Data(selectedRow, :) = [];
            end
        end

        % Button pushed function: ENDButton
        function ENDButtonPushed(app, event)
            % 1. 终止定时器或后台进程
            if isfield(app, 'MyTimer') && isvalid(app.MyTimer)
                stop(app.MyTimer);
                delete(app.MyTimer);
                app.MyTimer = [];
            end
        
            % 2. 终止任何可能的运行进程
            drawnow; % 确保 UI 线程可以执行
            if ~isempty(dbstack) % 检查是否有活动的回调函数
                return; % 终止当前函数的执行
            end
        
            % 3. 关闭等待状态 (如 uiwait, pause, 等)
            try
                uiresume(app.UIFigure); % 如果存在 uiwait，恢复 UI
            catch
            end
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            app.LogoutputTextArea.Value = '';
            OnReconstruct(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {733, 733};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {634, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CAMERADropDownLabel = uilabel(app.LeftPanel);
            app.CAMERADropDownLabel.Text = 'CAMERA';
            app.CAMERADropDown = uidropdown(app.LeftPanel);
            app.CAMERADropDown.Items = {'K3@ARM300', 'EMPAD@Themis', 'ARINA@ARM200'};
            app.CAMERADropDown.ValueChangedFcn = createCallbackFcn(app, @CAMERADropDownValueChanged, true);
            app.CAMERADropDown.Value = 'K3@ARM300';
            app.PtychopathEditFieldLabel = uilabel(app.LeftPanel);
            app.PtychopathEditFieldLabel.Text = 'Ptycho path';
            app.PtychopathEditField = uieditfield(app.LeftPanel, 'text');
            app.PtychopathEditField.Value = 'G:\Seafile\Research\STEM\Ptychography\shift2\fold_slice2103_202211_copy\ptycho';
            app.DatapathEditFieldLabel = uilabel(app.LeftPanel);
            app.DatapathEditFieldLabel.Text = 'Data path';
            app.DatapathEditField = uieditfield(app.LeftPanel, 'text');
            app.DatapathEditField.Value = 'G:\Data_ARM300\20250314 Bi2223 twist';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Scan number'; 'Total thickness'; 'N_layers'; 'N_iterations'; 'grouping'; 'beta_LSQ'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.gpu_idSpinnerLabel = uilabel(app.LeftPanel);
            app.gpu_idSpinnerLabel.Text = 'gpu_id';
            app.gpu_idSpinner = uispinner(app.LeftPanel);
            app.gpu_idSpinner.Value = 1;
            app.Np_presolveSpinnerLabel = uilabel(app.LeftPanel);
            app.Np_presolveSpinnerLabel.Text = 'Np_presolve';
            app.Np_presolveSpinner = uispinner(app.LeftPanel);
            app.Np_presolveSpinner.Value = 128;
            app.InitializepathandcameraLabel = uilabel(app.LeftPanel);
            app.InitializepathandcameraLabel.Text = {'1. Initialize path '; 'and camera'};
            app.InitializeButton = uibutton(app.LeftPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.PreparedataLabel = uilabel(app.LeftPanel);
            app.PreparedataLabel.Text = '2. Prepare data';
            app.PrepareButton = uibutton(app.LeftPanel, 'push');
            app.PrepareButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareButtonPushed, true);
            app.PrepareButton.Text = 'Prepare';
            app.UITable_2 = uitable(app.LeftPanel);
            app.UITable_2.ColumnName = {'Scan number'; 'df'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = true;
            app.FilenameEditFieldLabel = uilabel(app.LeftPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.LeftPanel, 'text');
            app.FilenameEditField.Value = 'STEM SI.dm4';
            app.DfolderpatternEditFieldLabel = uilabel(app.LeftPanel);
            app.DfolderpatternEditFieldLabel.Text = '4D folder pattern';
            app.DfolderpatternEditField = uieditfield(app.LeftPanel, 'text');
            app.DfolderpatternEditField.Value = '4D region*';
            app.selectButton = uibutton(app.LeftPanel, 'push');
            app.selectButton.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton.Text = 'select';
            app.selectButton_2 = uibutton(app.LeftPanel, 'push');
            app.selectButton_2.ButtonPushedFcn = createCallbackFcn(app, @selectButton_2Pushed, true);
            app.selectButton_2.Text = 'select';
            app.Np_bintoSpinnerLabel = uilabel(app.LeftPanel);
            app.Np_bintoSpinnerLabel.Text = 'Np_binto';
            app.Np_bintoSpinner = uispinner(app.LeftPanel);
            app.Np_bintoSpinner.Value = 128;
            app.Np_padtoSpinnerLabel = uilabel(app.LeftPanel);
            app.Np_padtoSpinnerLabel.Text = 'Np_padto';
            app.Np_padtoSpinner = uispinner(app.LeftPanel);
            app.Np_padtoSpinner.Value = 190;
            app.RotateCBEDSwitchLabel = uilabel(app.LeftPanel);
            app.RotateCBEDSwitchLabel.Text = 'Rotate CBED?';
            app.RotateCBEDSwitch = uiswitch(app.LeftPanel, 'slider');
            app.RotateCBEDSwitch.Items = {'No', 'Yes'};
            app.RotateCBEDSwitch.Value = 'Yes';
            app.SavedpSwitchLabel = uilabel(app.LeftPanel);
            app.SavedpSwitchLabel.Text = 'Save dp?';
            app.SavedpSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SavedpSwitch.Items = {'No', 'Yes'};
            app.SavedpSwitch.Value = 'Yes';
            app.PtychoreconstructionLabel = uilabel(app.LeftPanel);
            app.PtychoreconstructionLabel.Text = '3. Ptycho reconstruction';
            app.ReconstructButton = uibutton(app.LeftPanel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.Ptycho_GUIWrittenbyZehaoDongatTsinghuaUniversity20250331Label = uilabel(app.LeftPanel);
            app.Ptycho_GUIWrittenbyZehaoDongatTsinghuaUniversity20250331Label.Text = 'Ptycho_GUI, Written by Zehao Dong at Tsinghua University, 2025.03.31';
            app.AddRowButton = uibutton(app.LeftPanel, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'Add Row';
            app.DeleteRowButton = uibutton(app.LeftPanel, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonPushed, true);
            app.DeleteRowButton.Text = 'Delete Row';
            app.AddRowButton_3 = uibutton(app.LeftPanel, 'push');
            app.AddRowButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddRowButton_3Pushed, true);
            app.AddRowButton_3.Text = 'Add Row';
            app.DeleteRowButton_3 = uibutton(app.LeftPanel, 'push');
            app.DeleteRowButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButton_3Pushed, true);
            app.DeleteRowButton_3.Text = 'Delete Row';
            app.RightPanel = uipanel(app.GridLayout);
            app.alphaEditFieldLabel = uilabel(app.RightPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.RightPanel, 'numeric');
            app.alphaEditField.Value = 24.3;
            app.AccVoltEditFieldLabel = uilabel(app.RightPanel);
            app.AccVoltEditFieldLabel.Text = 'Acc. Volt.';
            app.AccVoltEditField = uieditfield(app.RightPanel, 'numeric');
            app.AccVoltEditField.Value = 300;
            app.StepsizeEditFieldLabel = uilabel(app.RightPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.RightPanel, 'numeric');
            app.StepsizeEditField.Value = 0.524;
            app.NitersaveSpinnerLabel = uilabel(app.RightPanel);
            app.NitersaveSpinnerLabel.Text = 'Niter save ';
            app.NitersaveSpinner = uispinner(app.RightPanel);
            app.NitersaveSpinner.Value = 200;
            app.NiterprobeupdSpinnerLabel = uilabel(app.RightPanel);
            app.NiterprobeupdSpinnerLabel.Text = 'Niter probe upd.';
            app.NiterprobeupdSpinner = uispinner(app.RightPanel);
            app.NiterprobeupdSpinner.Value = 10;
            app.NiterposcorrSpinnerLabel = uilabel(app.RightPanel);
            app.NiterposcorrSpinnerLabel.Text = 'Niter pos. corr.';
            app.NiterposcorrSpinner = uispinner(app.RightPanel);
            app.NiterposcorrSpinner.Value = 20;
            app.ProbemodeSpinnerLabel = uilabel(app.RightPanel);
            app.ProbemodeSpinnerLabel.Text = 'Probe mode';
            app.ProbemodeSpinner = uispinner(app.RightPanel);
            app.ProbemodeSpinner.Value = 2;
            app.blurPSFEditFieldLabel = uilabel(app.RightPanel);
            app.blurPSFEditFieldLabel.Text = 'blur (PSF)';
            app.blurPSFEditField = uieditfield(app.RightPanel, 'numeric');
            app.blurPSFEditField.Value = 1.5;
            app.NiterplotSpinnerLabel = uilabel(app.RightPanel);
            app.NiterplotSpinnerLabel.Text = 'Niter plot ';
            app.NiterplotSpinner = uispinner(app.RightPanel);
            app.NiterplotSpinner.Value = 10;
            app.reg_layerEditFieldLabel = uilabel(app.RightPanel);
            app.reg_layerEditFieldLabel.Text = 'reg_layer';
            app.reg_layerEditField = uieditfield(app.RightPanel, 'numeric');
            app.reg_layerEditField.Value = 0.3;
            app.removeambiguitySwitchLabel = uilabel(app.RightPanel);
            app.removeambiguitySwitchLabel.Text = 'remove ambiguity?';
            app.removeambiguitySwitch = uiswitch(app.RightPanel, 'slider');
            app.removeambiguitySwitch.Items = {'No', 'Yes'};
            app.removeambiguitySwitch.Value = 'Yes';
            app.HyperparametersnotoftenusedLabel = uilabel(app.RightPanel);
            app.HyperparametersnotoftenusedLabel.Text = 'Hyper-parameters not often used';
            app.UITable_3 = uitable(app.RightPanel);
            app.UITable_3.ColumnName = {'Scan number'; 'size_x'; 'size_y'};
            app.UITable_3.RowName = {};
            app.LogoutputTextAreaLabel = uilabel(app.RightPanel);
            app.LogoutputTextAreaLabel.Text = 'Log output';
            app.LogoutputTextArea = uitextarea(app.RightPanel);
            app.rbfEditFieldLabel = uilabel(app.RightPanel);
            app.rbfEditFieldLabel.Text = 'rbf';
            app.rbfEditField = uieditfield(app.RightPanel, 'numeric');
            app.rbfEditField.Value = 79.26;
            app.ENDButton = uibutton(app.RightPanel, 'push');
            app.ENDButton.ButtonPushedFcn = createCallbackFcn(app, @ENDButtonPushed, true);
            app.ENDButton.Text = 'END';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ptycho_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end